classdef IAD_acquire < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        k                               matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        AcquisitionTab                  matlab.ui.container.Tab
        SphereSelectSwitch              matlab.ui.control.Switch
        SphereSelectSwitchLabel         matlab.ui.control.Label
        OpticalSwitchConnectButton      matlab.ui.control.Button
        SaturatedLampLabel              matlab.ui.control.Label
        IntegrationTimemsEditFieldLabel  matlab.ui.control.Label
        OutputNameEditFieldLabel        matlab.ui.control.Label
        SaturatedLamp                   matlab.ui.control.Lamp
        DarkLamp                        matlab.ui.control.Lamp
        ConnectedLamp                   matlab.ui.control.Lamp
        ConnectButton                   matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        OutputNameEditField             matlab.ui.control.EditField
        AcquireDataButton               matlab.ui.control.Button
        AutoexposeButton                matlab.ui.control.Button
        AcquireDarkButton               matlab.ui.control.Button
        InttimeEditField                matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
        ProcessingTab                   matlab.ui.container.Tab
        StandardReflectanceLabel_2      matlab.ui.control.Label
        SystemTypeButtonGroup           matlab.ui.container.ButtonGroup
        CustomButton                    matlab.ui.control.RadioButton
        DualButton                      matlab.ui.control.RadioButton
        SingleButton                    matlab.ui.control.RadioButton
        RStdListbox                     matlab.ui.control.ListBox
        StandardReflectanceLabel        matlab.ui.control.Label
        TransmittanceLabel              matlab.ui.control.Label
        TStdListbox                     matlab.ui.control.ListBox
        TMeasListbox                    matlab.ui.control.ListBox
        ReflectanceListBoxLabel         matlab.ui.control.Label
        RMeasListbox                    matlab.ui.control.ListBox
        uipanel1                        matlab.ui.container.Panel
        StepSizeEditField               matlab.ui.control.NumericEditField
        StepSizeEditFieldLabel          matlab.ui.control.Label
        SampleIndexofRefractionEditField  matlab.ui.control.NumericEditField
        SampleIndexofRefractionEditFieldLabel  matlab.ui.control.Label
        SampleThicknessmmEditField      matlab.ui.control.NumericEditField
        SampleThicknessmmEditFieldLabel  matlab.ui.control.Label
        AnisotropyEditField             matlab.ui.control.NumericEditField
        AnisotropyEditFieldLabel        matlab.ui.control.Label
        uipanel2                        matlab.ui.container.Panel
        SlidesPresentSwitch             matlab.ui.control.Switch
        SlidesPresentSwitchLabel        matlab.ui.control.Label
        SlideIndexofRefractionEditField  matlab.ui.control.NumericEditField
        SlideIndexofRefractionEditFieldLabel  matlab.ui.control.Label
        SlideThicknessmmEditField       matlab.ui.control.NumericEditField
        SlideThicknessmmEditFieldLabel  matlab.ui.control.Label
        SelectDirButton                 matlab.ui.control.Button
        DataDirectoryText               matlab.ui.control.Label
        SaveFileNameEditFieldLabel      matlab.ui.control.Label
        SaveFileNameEditField           matlab.ui.control.EditField
        ResetButton                     matlab.ui.control.Button
        CalculateButton                 matlab.ui.control.Button
        MuspAxes                        matlab.ui.control.UIAxes
        MuaAxes                         matlab.ui.control.UIAxes
        RawAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)

        OldPath = ""

        % coefs = struct( ...
        %     'a0',3.20180e+02,'a1',8.50798e-1, ...
        %     'a2',-1.09490e-4,'a3',6.30802e-09 ...
        %     );                      % Calibration coefficients (CAC 230619)

        DataPtr     % Pointer to memory location for collected data

        LampOn = [0, 1, 0];         % Color to indicate ready status
        LampOff = [0.6, 0.6, 0.6];  % Color to indicate non-ready status

        IadInputFileName="temp.txt" % Overwrite this file to feed iad.exe
        IadInputHeader="SingleSphereParams" % Contains the header for iad.exe

        IadOutDir = "procdata"

    end
    
    properties (Access = public)

        Spec    spectrometer    % Object of "Spectrometer" class
        Switch  % Optical switch

        DarkData (:,1) double
        RawData (:,1) double

        Initialized (1,1) logical = false % Are we connected?
        HasDark  (1,1) logical = false % Has valid dark scan been taken?
        Px       (:,1) double   % Pixel indices. 0:Spec.NumPixels-1

        RawDir   (1,1) string   % Path to folder containing all data files
        Fnames   (:,1) string   % Filenames in DataDir wihout the ".txt"

        Wv       (:,1) double   % Vector of wavelengths in nm
        R_sample (:,3) double   % Reflectance spectrum
        T_sample (:,3) double   % Transmittance spectrum
        R_std    (:,3) double   % Calibration standard reflectance
        T_std    (:,3) double   % Transmittance reference reflectance

        Mr2      (:,1) double   % Corrected reflectance   
        Mt2      (:,1) double   % Corrected Transmittance

    end
    
    methods (Access = private)

        % *****************************************************************
        % PRIVATE METHODS FOR ACQUISITION TAB
        % *****************************************************************
 
        function PlotData(app)

            if app.HasDark
                pdata = app.RawData-app.DarkData;
                plot(app.UIAxes, app.Spec.WvArray, pdata, 'g')
                title(app.UIAxes, 'Dark-Corrected Spectrum')
            else
                pdata = app.RawData;
                plot(app.UIAxes, app.Spec.WvArray, app.RawData)
                title(app.UIAxes, 'Raw Spectrum')
            end
            app.UIAxes.YAxis.Exponent = 0;
            if max(pdata) >= 65500
                app.SaturatedLamp.Color = app.LampOn;
            else
                app.SaturatedLamp.Color = app.LampOff;
            end
            
        end

        function PlotDarkData(app)
            plot(app.UIAxes, app.Spec.WvArray, app.DarkData, ...
                "Color", [0.4, 0.4, 0.4])
            app.UIAxes.YAxis.Exponent = 0;
            title(app.UIAxes, 'Dark Spectrum')
        end
        
        function EnableAll(app,state)

            [ ...
            app.InttimeEditField.Enable, ...
            app.AutoexposeButton.Enable, ...
            app.AcquireDarkButton.Enable, ...
            app.AcquireDataButton.Enable, ...
            app.OutputNameEditField.Enable, ...
            app.SaveButton.Enable ...
            ] = deal(state);
            
        end

        
        function d = WaitForSpec(app)

            d = uiprogressdlg(app.k, "Message", "Acquiring", ...
                "Title", "Please Wait", "Value", 1);
         
        end

        % *****************************************************************
        % PRIVATE METHODS FOR PROCESSING TAB
        % *****************************************************************

        function PopulateListBoxes(app)

            app.sliceDirString;

            d = dir(fullfile(app.RawDir,"*.txt"));
            vals = string({d.name}').extractBefore('.txt');

            if ~isempty(vals)
                [app.RMeasListbox.Items, ...
                    app.TMeasListbox.Items, ...
                    app.RStdListbox.Items, ...
                    app.TStdListbox.Items] = deal(vals);
            else
                [app.RMeasListbox.Items, ...
                    app.TMeasListbox.Items, ...
                    app.RStdListbox.Items, ...
                    app.TStdListbox.Items] = deal("No Data Found.");
            end

        end

        function GatherValues(app)

            % Assume, for now, that all files are correctly formatted.
            app.R_sample = load(fullfile(app.RawDir, ...
                app.RMeasListbox.Value + ".txt"));
            app.T_sample = load(fullfile(app.RawDir, ...
                app.TMeasListbox.Value + ".txt"));
            app.R_std = load(fullfile(app.RawDir, ...
                app.RStdListbox.Value + ".txt"));
            app.T_std = load(fullfile(app.RawDir, ...
                app.TStdListbox.Value + ".txt"));
            app.Wv = app.R_sample(:,2);
            
            %system correction method for single sphere measurements...
            app.Mr2=(app.R_sample(:,3))./(app.R_std(:,3));
            app.Mt2=(app.T_sample(:,3))./(app.T_std(:,3));

        end

        
        function err = VerifyData(app)

            err = [];
            if isempty(app.RMeasListbox.Value) || ...
                    isempty(app.TMeasListbox.Value) || ...
                    isempty(app.RStdListbox.Value) || ...
                    isempty(app.TStdListbox.Value)
                msgbox("Please select four files first.")
                err = [err, 1];
            end

        end        


        function WriteInputFile(app)

            % Adjust header values to match app
            outFileName= fullfile(app.IadOutDir, "temp.txt");

            % These replacements all rely on the specified order. As such,
            % empty lines must be skipped for the replacement to work.
            header = readlines(app.IadInputHeader,'EmptyLineRule','skip');
            header(2) = strrep(header(2), "1.33", ...
                num2str(app.SampleIndexofRefractionEditField.Value));
            header(4) = strrep(header(4), "2.00", ...
                num2str(app.SampleThicknessmmEditField.Value));
            
            if strcmp(app.SlidesPresentSwitch.Value,"No")
                header(3) = strrep(header(3),"1.56", "1.0");
                header(5) = strrep(header(5),"1.23", "0.0");
            else
                header(3) = strrep(header(3),"1.56", ...
                    num2str(app.SlideIndexofRefractionEditField.Value));
                header(5) = strrep(header(5),"1.23", ...
                    num2str(app.SlideThicknessmmEditField.Value));
            end
                           
            % Put the data in an array for readability
            stepsize = app.StepSizeEditField.Value;
            dataMat = [ ...
                app.Wv(1:stepsize:end)'; ...
                app.Mr2(1:stepsize:end)'; ...
                app.Mt2(1:stepsize:end)'];

            % Write to file
            writelines(header, outFileName);
            fid = fopen(outFileName,'a');
            fprintf(fid,'%f  %f  %f\n', dataMat);
            fclose(fid);

        end        
        

        function PlotCorrectedData(app)

            plot(app.RawAxes, app.Wv, app.Mr2, app.Wv, app.Mt2)
            legend(app.RawAxes, "Reflectance", "Transmittance", ...
                "Location", "Best")
            xlabel(app.RawAxes, 'Wavelength (nm)')
            ylabel(app.RawAxes, 'Calibrated Reflectance/Transmittance')
            drawnow

        end


        function PlotOPs(app)

            fid = fopen(fullfile(app.IadOutDir, ...
                app.SaveFileNameEditField.Value+"_out.txt"),'r');
            for i=1:44  %skip first 44 lines in output file (header info)
                fgetl(fid);
            end
            
            % These file ending chars are a pain.
            data = nan(ceil(numel(app.Wv)/app.StepSizeEditField.Value),9);
            idx = 0;
            while ~feof(fid)
                idx = idx+1;
                data(idx,:) = double( ...
                    string(fgetl(fid)).strip('left'). ...
                    extractBefore('# *').split);
            end
            fclose(fid);

            outwv = data(:,1);
            mua = data(:,6);
            musp = data(:,7);

            plot(app.MuaAxes,outwv,mua)
            xlabel(app.MuaAxes, "Wavelength (nm)")
            ylabel(app.MuaAxes, "\mu_A (mm^-^1)")

            plot(app.MuspAxes,outwv,musp)
            xlabel(app.MuspAxes, "Wavelength (nm)")
            ylabel(app.MuspAxes, "\mu_S' (mm^-^1)")
            
        end        
        
        function sliceDirString(app)

            % Not sure if this is the best way to slice up the string...
            txtwid = app.DataDirectoryText.Position(3)/6;
            words = split(app.RawDir,filesep);
            splittext = strings(0);
            while ~isempty(words)
                lastidx = find(cumsum(strlength(words)+1) <= ...
                    txtwid,1,'last');
                splittext = [splittext; ...
                    words(1:lastidx).join(filesep) + filesep];
                words = words(lastidx+1:end);
            end

            app.DataDirectoryText.Text = splittext;            

        end


    end
    
    methods (Access = public)

        % *****************************************************************
        % PUBLIC METHODS FOR ACQUISITION TAB
        % *****************************************************************        
        
        function results = Acquire(app)
    
            d = app.WaitForSpec;
            app.Spec = ...
                app.Spec.setIntegrationTime(app.InttimeEditField.Value);    
            results = app.Spec.acquire;
            close(d)
            
        end
        
        function SetIntTime(app, inttime)           

            inttime = round(inttime);
            app.InttimeEditField.Value = inttime;
            app.InttimeEditFieldValueChanged;
       
        end

        
        function status = LoadSpec(app)
    
            % [indx,tf] = listdlg('ListString',list, ...
            %     'PromptString', 'Please Select a Spectrometer');
            app.Spec = wasatchNIR1Spec;
            status = 1;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            cd(fileparts(mfilename('fullpath')))
            app.RawDir = fullfile(pwd,'rawdata');
            addpath('./hardware/');

            % Acquisition tab:
            app.LoadSpec;

            app.ConnectedLamp.Color = app.LampOff;
            app.DarkLamp.Color = app.LampOff;

            % Processing tab:
            % Attempt to populate listboxes
            app.PopulateListBoxes;        

        end

        % Button pushed function: AcquireDataButton
        function AcquireDataButtonPushed(app, event)
           
            app.RawData = app.Acquire;
            app.PlotData;

        end

        % Value changed function: InttimeEditField
        function InttimeEditFieldValueChanged(app, event)
            
            app.DarkLamp.Color = app.LampOff;
            app.DarkData = zeros(size(app.Px));
            app.HasDark = false;
            
        end

        % Close request function: k
        function kCloseRequest(app, event)
            try
                app.Spec.closeSpec;
            catch
            end

            warning('off','MATLAB:rmpath:DirNotFound')
            rmpath("./hardware/")
            warning('on','MATLAB:rmpath:DirNotFound')

            delete(app)

        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            
            [app.Spec, status] = app.Spec.initSpec;
            
            if status ~= 1
                app.Initialized = false;
                app.ConnectedLamp.Color = app.LampOff;
                app.EnableAll("off");

            else
                app.Initialized = true;
                app.ConnectedLamp.Color = app.LampOn;
                app.EnableAll("on");

                app.Px = (0:(app.Spec.NumPixels-1))';
                [app.DarkData, app.RawData] = deal(zeros(size(app.Px)));                
                app.UIAxes.XLim = ...
                    [min(app.Spec.WvArray), max(app.Spec.WvArray)];
            end

        end

        % Button pushed function: AutoexposeButton
        function AutoexposeButtonPushed(app, event)
            
            autoX = false;
            tries = 0;
            maxsig = 0;

            app.SetIntTime(app.InttimeEditField.Value);
            while ~autoX
            
                thisIntTime = app.InttimeEditField.Value;
                app.RawData = app.Acquire;
                oldmax = maxsig;
                    maxsig = max(app.RawData);
    
                if maxsig > 10000 && maxsig < 65535
                    app.SetIntTime(min(65535, max(50, ...
                        round((63000/maxsig)*thisIntTime))));
                    app.RawData = app.Acquire;
                    autoX = true;
                        
                elseif maxsig <= 10000 
                    if thisIntTime < 65535

                        if tries >= 2 && maxsig/oldmax <= 2                            
                            uialert(app.k, ...
                                ["Weak signal detected."; ...
                                "Autoexpose failed."], "No Signal")
                            autoX = true;
                        else
                            app.SetIntTime(min(10*thisIntTime, 65535));
                            tries = tries+1;
                        end

                    end

                elseif maxsig >= 65000
                    if thisIntTime == 50
                        uialert(app.k, ...
                            "Saturation Detected", "Saturated");
                        autoX = true;

                    elseif maxsig == 65535
                        app.SetIntTime(max(50, round(0.3*thisIntTime)));

                    else
                        app.SetIntTime(min(65535, max(50, ...
                            round((63000/maxsig)*thisIntTime))));

                    end                                                            

                end
            end
            app.PlotData;

        end

        % Button pushed function: AcquireDarkButton
        function AcquireDarkButtonPushed(app, event)
            
            app.DarkData = app.Acquire;
            app.DarkLamp.Color = app.LampOn;
            app.HasDark = true;
            app.PlotDarkData;

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            if not(app.HasDark)
                uiconfirm(app.IAD_UI,'No valid dark data! Continue?', ...
                    'Zero Dark Thirty','Icon','warning')
            end

            fname = fullfile("rawdata", ...
                app.OutputNameEditField.Value + ".txt");

            d = dir(fname);
            if ~isempty(d)
                selection = uiconfirm(app.k, ...
                    'Overwrite Saved Data?', 'Confirm Overwrite',...
                    'Icon','warning');
                if strcmp(selection, "OK")
                    writematrix( ...
                        [app.Px, app.Spec.WvArray, ...
                        (app.RawData-app.DarkData)./ ...
                        (app.InttimeEditField.Value/1000)], ...
                        fname);
                    uialert(app.k, "File Saved", "", ...
                        "Icon", "Success")
                else
                    uialert(app.k, "File not saved.", "", ...
                        "Icon", "Warning")
                end
            else
                writematrix( ...
                    [app.Px,app.Spec.WvArray, ...
                    (app.RawData-app.DarkData)./ ...
                    (app.InttimeEditField.Value/1000)], ...
                    fname);
                uialert(app.k, ...
                    "File Saved", "", "Icon", "Success")
            end


        end

        % Value changed function: SlidesPresentSwitch
        function SlidesPresentSwitchValueChanged(app, event)
            
            switch app.SlidesPresentSwitch.Value
                case ("No")
                    app.SlideIndexofRefractionEditField.Enable = "off";
                    app.SlideThicknessmmEditField.Enable = "off";
                case ("Yes")
                    app.SlideIndexofRefractionEditField.Enable = "on";
                    app.SlideThicknessmmEditField.Enable = "on";
            end  
            
        end

        % Selection changed function: SystemTypeButtonGroup
        function SystemTypeButtonGroupSelectionChanged(app, event)

            app.IadInputHeader = ...
                app.SystemTypeButtonGroup.SelectedObject.Tag + ".txt";
            
        end

        % Button pushed function: SelectDirButton
        function SelectDirButtonPushed(app, event)
            
            % Try to begin in "rawata" folder colocated with app. 
            app.RawDir = uigetdir(app.RawDir);
            
            app.PopulateListBoxes;
            %Bring app back to the front
            focus(app.k)

        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            
            err = app.VerifyData;
            if isempty(err)
                app.GatherValues;
                app.PlotCorrectedData;
                app.WriteInputFile;
            
                commandwindow

                % Orange text hack from Yair Altman/Carsten Allefeld
                fprintf('\nUsing Parameters from: [\b%s]\b', ...
                    app.IadInputHeader);
                fprintf('\nCalculating OPs for [\b%g]\b wavelengths.\n', ...
                    numel(app.Wv(1:app.StepSizeEditField.Value:end)));

                cmd = "iad -g " + app.AnisotropyEditField.Value + ...
                    " -o " + fullfile(app.IadOutDir, ...
                    app.SaveFileNameEditField.Value+"_out.txt") + ...
                    fullfile(" " + app.IadOutDir, app.IadInputFileName);

                if ispc
                    system(cmd);
                    app.PlotOPs;
                else
                    error("*ix systems not tested yet")
                end

                focus(app.k)
            end

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            
            % Clear DataDir
            app.DataDirectoryText.Text = "";
            % Clear list boxes
            [app.RMeasListbox.Items, ...
                app.TMeasListbox.Items, ...
                app.RStdListbox.Items] = deal("");

            % Reset input parameters
            app.AnisotropyEditField.Value = 0.7;
            app.SampleThicknessmmEditField.Value = 2;
            app.SampleIndexofRefractionEditField.Value = 1.33;
            app.StepSizeEditField.Value = 1;

            % Slide Value Panel
            app.SlideThicknessmmEditField.Value = 1.23;
            app.SlideIndexofRefractionEditField.Value = 1.56;
            app.SlidesPresentSwitch.Value = "No";
            app.SlidesPresentSwitchValueChanged;

        end

        % Button down function: ProcessingTab
        function ProcessingTabButtonDown(app, event)
            app.PopulateListBoxes
        end

        % Button pushed function: OpticalSwitchConnectButton
        function OpticalSwitchConnectButtonPushed(app, event)
            app.Switch = serialport("COM3",9600,"Timeout",5);
            app.SphereSelectSwitch.Visible = "on";
            app.SphereSelectSwitch.Enable = "on";
        end

        % Value changed function: SphereSelectSwitch
        function SphereSelectSwitchValueChanged(app, event)
            value = app.SphereSelectSwitch.Value;
            write(app.Switch,[1,18,0,str2double(value)],"uint8");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.k = uifigure('Visible', 'off');
            app.k.Name = 'MATLAB App';
            app.k.CloseRequestFcn = createCallbackFcn(app, @kCloseRequest, true);
            app.TabGroup = uitabgroup(app.k);
            app.AcquisitionTab = uitab(app.TabGroup);
            app.AcquisitionTab.Title = 'Acquisition';
            app.UIAxes = uiaxes(app.AcquisitionTab);
            title(app.UIAxes, 'No Data')
            xlabel(app.UIAxes, 'Wavelength (nm)')
            ylabel(app.UIAxes, 'Counts')
            app.UIAxes.YLim = [-10 65535];
            app.InttimeEditField = uieditfield(app.AcquisitionTab, 'numeric');
            app.InttimeEditField.Limits = [50 65535];
            app.InttimeEditField.RoundFractionalValues = 'on';
            app.InttimeEditField.ValueDisplayFormat = '%.8g';
            app.InttimeEditField.ValueChangedFcn = createCallbackFcn(app, @InttimeEditFieldValueChanged, true);
            app.InttimeEditField.Enable = 'off';
            app.InttimeEditField.Value = 50;
            app.AcquireDarkButton = uibutton(app.AcquisitionTab, 'push');
            app.AcquireDarkButton.ButtonPushedFcn = createCallbackFcn(app, @AcquireDarkButtonPushed, true);
            app.AcquireDarkButton.Enable = 'off';
            app.AcquireDarkButton.Text = 'Acquire Dark';
            app.AutoexposeButton = uibutton(app.AcquisitionTab, 'push');
            app.AutoexposeButton.ButtonPushedFcn = createCallbackFcn(app, @AutoexposeButtonPushed, true);
            app.AutoexposeButton.Enable = 'off';
            app.AutoexposeButton.Text = 'Autoexpose';
            app.AcquireDataButton = uibutton(app.AcquisitionTab, 'push');
            app.AcquireDataButton.ButtonPushedFcn = createCallbackFcn(app, @AcquireDataButtonPushed, true);
            app.AcquireDataButton.Enable = 'off';
            app.AcquireDataButton.Text = 'Acquire Data';
            app.OutputNameEditField = uieditfield(app.AcquisitionTab, 'text');
            app.OutputNameEditField.Enable = 'off';
            app.OutputNameEditField.Value = 'Intralipid_T';
            app.SaveButton = uibutton(app.AcquisitionTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.ConnectButton = uibutton(app.AcquisitionTab, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = {'CONNECT'; 'TO'; 'SPECTROMETER'};
            app.ConnectedLamp = uilamp(app.AcquisitionTab);
            app.DarkLamp = uilamp(app.AcquisitionTab);
            app.SaturatedLamp = uilamp(app.AcquisitionTab);
            app.OutputNameEditFieldLabel = uilabel(app.AcquisitionTab);
            app.OutputNameEditFieldLabel.Text = 'Output Name';
            app.IntegrationTimemsEditFieldLabel = uilabel(app.AcquisitionTab);
            app.IntegrationTimemsEditFieldLabel.Text = 'Integration Time (ms)';
            app.SaturatedLampLabel = uilabel(app.AcquisitionTab);
            app.SaturatedLampLabel.Text = 'Saturated';
            app.OpticalSwitchConnectButton = uibutton(app.AcquisitionTab, 'push');
            app.OpticalSwitchConnectButton.ButtonPushedFcn = createCallbackFcn(app, @OpticalSwitchConnectButtonPushed, true);
            app.OpticalSwitchConnectButton.Text = 'Optical Switch Connect';
            app.SphereSelectSwitchLabel = uilabel(app.AcquisitionTab);
            app.SphereSelectSwitchLabel.Enable = 'off';
            app.SphereSelectSwitchLabel.Visible = 'off';
            app.SphereSelectSwitchLabel.Text = 'Sphere Select';
            app.SphereSelectSwitch = uiswitch(app.AcquisitionTab, 'slider');
            app.SphereSelectSwitch.Items = {'1', '2'};
            app.SphereSelectSwitch.ValueChangedFcn = createCallbackFcn(app, @SphereSelectSwitchValueChanged, true);
            app.SphereSelectSwitch.Enable = 'off';
            app.SphereSelectSwitch.Visible = 'off';
            app.SphereSelectSwitch.Value = '1';
            app.ProcessingTab = uitab(app.TabGroup);
            app.ProcessingTab.Title = 'Processing';
            app.ProcessingTab.ButtonDownFcn = createCallbackFcn(app, @ProcessingTabButtonDown, true);
            app.RawAxes = uiaxes(app.ProcessingTab);
            xlabel(app.RawAxes, 'Wavelength (nm)')
            ylabel(app.RawAxes, 'Calibrated Reflectance/Transmittance')
            app.RawAxes.Tag = 'axes1';
            app.MuaAxes = uiaxes(app.ProcessingTab);
            xlabel(app.MuaAxes, 'Wavelength (nm)')
            ylabel(app.MuaAxes, '\mu_A (mm^-^1)')
            app.MuaAxes.XLim = [400 1100];
            app.MuaAxes.YLim = [0 0.1];
            app.MuaAxes.Tag = 'axes2';
            app.MuspAxes = uiaxes(app.ProcessingTab);
            xlabel(app.MuspAxes, 'Wavelength (nm)')
            ylabel(app.MuspAxes, '\mu_S'' (mm^-^1)')
            app.MuspAxes.Tag = 'axes3';
            app.CalculateButton = uibutton(app.ProcessingTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Tag = 'Calculate';
            app.CalculateButton.Text = 'Calculate';
            app.ResetButton = uibutton(app.ProcessingTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Tag = 'reset';
            app.ResetButton.Text = 'Reset';
            app.SaveFileNameEditField = uieditfield(app.ProcessingTab, 'text');
            app.SaveFileNameEditField.Value = 'test';
            app.SaveFileNameEditFieldLabel = uilabel(app.ProcessingTab);
            app.SaveFileNameEditFieldLabel.Text = 'Save File Name';
            app.DataDirectoryText = uilabel(app.ProcessingTab);
            app.DataDirectoryText.Tag = 'text9';
            app.DataDirectoryText.Text = '';
            app.SelectDirButton = uibutton(app.ProcessingTab, 'push');
            app.SelectDirButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDirButtonPushed, true);
            app.SelectDirButton.Tag = 'pushbutton3';
            app.SelectDirButton.Text = {'Select Raw Data'; 'Directory'};
            app.uipanel2 = uipanel(app.ProcessingTab);
            app.uipanel2.Title = 'Slide/Cuvette Parameters';
            app.uipanel2.Tag = 'uipanel2';
            app.SlideThicknessmmEditFieldLabel = uilabel(app.uipanel2);
            app.SlideThicknessmmEditFieldLabel.Text = 'Slide Thickness (mm)';
            app.SlideThicknessmmEditField = uieditfield(app.uipanel2, 'numeric');
            app.SlideThicknessmmEditField.Limits = [0 100];
            app.SlideThicknessmmEditField.Value = 1.23;
            app.SlideIndexofRefractionEditFieldLabel = uilabel(app.uipanel2);
            app.SlideIndexofRefractionEditFieldLabel.Text = 'Slide Index of Refraction';
            app.SlideIndexofRefractionEditField = uieditfield(app.uipanel2, 'numeric');
            app.SlideIndexofRefractionEditField.Value = 1.56;
            app.SlidesPresentSwitchLabel = uilabel(app.uipanel2);
            app.SlidesPresentSwitchLabel.Text = 'Slides Present?';
            app.SlidesPresentSwitch = uiswitch(app.uipanel2, 'slider');
            app.SlidesPresentSwitch.Items = {'No', 'Yes'};
            app.SlidesPresentSwitch.ValueChangedFcn = createCallbackFcn(app, @SlidesPresentSwitchValueChanged, true);
            app.SlidesPresentSwitch.Value = 'Yes';
            app.uipanel1 = uipanel(app.ProcessingTab);
            app.uipanel1.Title = 'Input Parameters';
            app.uipanel1.Tag = 'uipanel1';
            app.AnisotropyEditFieldLabel = uilabel(app.uipanel1);
            app.AnisotropyEditFieldLabel.Text = 'Anisotropy';
            app.AnisotropyEditField = uieditfield(app.uipanel1, 'numeric');
            app.AnisotropyEditField.Limits = [-1 1];
            app.AnisotropyEditField.Value = 0.7;
            app.SampleThicknessmmEditFieldLabel = uilabel(app.uipanel1);
            app.SampleThicknessmmEditFieldLabel.Text = 'Sample Thickness (mm)';
            app.SampleThicknessmmEditField = uieditfield(app.uipanel1, 'numeric');
            app.SampleThicknessmmEditField.Limits = [0 100];
            app.SampleThicknessmmEditField.Value = 2;
            app.SampleIndexofRefractionEditFieldLabel = uilabel(app.uipanel1);
            app.SampleIndexofRefractionEditFieldLabel.Text = 'Sample Index of Refraction';
            app.SampleIndexofRefractionEditField = uieditfield(app.uipanel1, 'numeric');
            app.SampleIndexofRefractionEditField.Limits = [0 Inf];
            app.SampleIndexofRefractionEditField.Value = 1.33;
            app.StepSizeEditFieldLabel = uilabel(app.uipanel1);
            app.StepSizeEditFieldLabel.Text = 'Step Size';
            app.StepSizeEditField = uieditfield(app.uipanel1, 'numeric');
            app.StepSizeEditField.Limits = [1 Inf];
            app.StepSizeEditField.RoundFractionalValues = 'on';
            app.StepSizeEditField.ValueDisplayFormat = '%.u';
            app.StepSizeEditField.Value = 1;
            app.RMeasListbox = uilistbox(app.ProcessingTab);
            app.RMeasListbox.Items = {};
            app.RMeasListbox.Value = {};
            app.ReflectanceListBoxLabel = uilabel(app.ProcessingTab);
            app.ReflectanceListBoxLabel.Text = 'Reflectance';
            app.TMeasListbox = uilistbox(app.ProcessingTab);
            app.TMeasListbox.Items = {};
            app.TMeasListbox.Value = {};
            app.TStdListbox = uilistbox(app.ProcessingTab);
            app.TStdListbox.Items = {};
            app.TStdListbox.Value = {};
            app.TransmittanceLabel = uilabel(app.ProcessingTab);
            app.TransmittanceLabel.Text = 'Transmittance';
            app.StandardReflectanceLabel = uilabel(app.ProcessingTab);
            app.StandardReflectanceLabel.Text = 'Reflectance Reference';
            app.RStdListbox = uilistbox(app.ProcessingTab);
            app.RStdListbox.Items = {};
            app.RStdListbox.Value = {};
            app.SystemTypeButtonGroup = uibuttongroup(app.ProcessingTab);
            app.SystemTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SystemTypeButtonGroupSelectionChanged, true);
            app.SystemTypeButtonGroup.Title = 'System Type';
            app.SingleButton = uiradiobutton(app.SystemTypeButtonGroup);
            app.SingleButton.Tag = 'SingleSphereParams';
            app.SingleButton.Text = 'Single';
            app.SingleButton.Value = true;
            app.DualButton = uiradiobutton(app.SystemTypeButtonGroup);
            app.DualButton.Tag = 'DualSphereParams';
            app.DualButton.Text = 'Dual';
            app.CustomButton = uiradiobutton(app.SystemTypeButtonGroup);
            app.CustomButton.Tag = 'CustomSphereParams';
            app.CustomButton.Text = 'Custom';
            app.StandardReflectanceLabel_2 = uilabel(app.ProcessingTab);
            app.StandardReflectanceLabel_2.Text = 'Transmittance Reference';
            % Show the figure after all components are created
            app.k.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IAD_acquire

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.k)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.k)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.k)
        end
    end
end